classdef main_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        SunSynchronousOrbitPanel        matlab.ui.container.Panel
        AltitudekmEditFieldLabel        matlab.ui.control.Label
        AltitudekmEditField             matlab.ui.control.NumericEditField
        PeriodminEditFieldLabel         matlab.ui.control.Label
        PeriodminEditField              matlab.ui.control.NumericEditField
        EclipsePeriodminEditFieldLabel  matlab.ui.control.Label
        EclipsePeriodminEditField       matlab.ui.control.NumericEditField
        SunIlluminatedPeriodminEditFieldLabel  matlab.ui.control.Label
        SunIlluminatedPeriodminEditField  matlab.ui.control.NumericEditField
        ButtonGroup                     matlab.ui.container.ButtonGroup
        DawnduskButton                  matlab.ui.control.RadioButton
        NoonmidnightButton              matlab.ui.control.RadioButton
        TypeLabel                       matlab.ui.control.Label
        NumberofOrbitsDropDownLabel     matlab.ui.control.Label
        NumberofOrbitsDropDown          matlab.ui.control.DropDown
        SpacecraftPropertiesPanel       matlab.ui.container.Panel
        InitialTemperatureCEditFieldLabel  matlab.ui.control.Label
        InitialTemperatureCEditField    matlab.ui.control.NumericEditField
        ShapeDropDownLabel              matlab.ui.control.Label
        ShapeDropDown                   matlab.ui.control.DropDown
        RadiusmEditFieldLabel           matlab.ui.control.Label
        RadiusmEditField                matlab.ui.control.NumericEditField
        MasskgEditFieldLabel            matlab.ui.control.Label
        MasskgEditField                 matlab.ui.control.NumericEditField
        CoatingDropDownLabel            matlab.ui.control.Label
        CoatingDropDown                 matlab.ui.control.DropDown
        ThermalCapacityJKgKEditFieldLabel  matlab.ui.control.Label
        ThermalCapacityJKgKEditField    matlab.ui.control.NumericEditField
        RadiatingHeatWEditFieldLabel    matlab.ui.control.Label
        RadiatingHeatWEditField         matlab.ui.control.NumericEditField
        ClearButton                     matlab.ui.control.Button
        SimulateButton                  matlab.ui.control.Button
        LegendPanel                     matlab.ui.container.Panel
        borbitbetaangleCheckBox         matlab.ui.control.CheckBox
        haltitudeCheckBox               matlab.ui.control.CheckBox
        aecoatingCheckBox               matlab.ui.control.CheckBox
        rradiusCheckBox                 matlab.ui.control.CheckBox
        mmassCheckBox                   matlab.ui.control.CheckBox
        cthermalcapacityCheckBox        matlab.ui.control.CheckBox
        t0initialtemperatureCheckBox    matlab.ui.control.CheckBox
        qsradiatingheatCheckBox         matlab.ui.control.CheckBox
    end


    properties (Access = public)
        % Standard gravitational parameter for Earth. 
        GMe = 398600; % km^3/s^-2 
        
        % Stefan-Boltzmann constant.
        sigma = 5.67e-8; % W/(m^2*K^4)
        
        % Earth radius.
        Re = 6378; % km
        
        % Default orbit beta angle.
        orbit_beta = 0;
        
        % Data to plot.
        time_span_list = {};
        T_C_list = {};
        plot_legend = {};
   
    end

    methods (Access = public)
    
        function T_orb = orbit_T(app)
            h = app.AltitudekmEditField.Value;
            
            % Orbit period.
            T_orb = 2 * pi * sqrt((app.Re + h)^3 / app.GMe);
        end
        
        function fE = eclipse_fraction(app)
            % To calculate the eclipse period and the sun illumination period, first 
            % calculate the spacecraft eclipse fraction using the equation in the
            % following source:
            %
            % Satellite Engineering, Bill Nadie.
            % Equation 4 under section "Calculation of Eclipse Time."
            % Fall 2003.

            beta = app.orbit_beta;
            h = app.AltitudekmEditField.Value;
            
            % Eclipse fraction.
            fE = (1/pi) * acos(sqrt(h^2 + 2 * app.Re * h) / ((app.Re + h) * cos(beta)));
            
            % Selecting a wrong value for h when beta is 90 degrees can result
            % in an imaginary value for fE. Handle this by reseting h to 600.
            % FIXME: Handle this in a more elegant manner.
            if ~isreal(fE)
               error_msg = 'Calculating the eclipse fraction of the orbit can result in an imaginary value when selecting an altitude that does not play nicely with the orbit dawn-dusk beta angle value (i.e 90 deg). This is what happened here, reverting back to default altitude value of 600 km.';
               uialert(app.UIFigure, error_msg, 'Error');
                
               app.AltitudekmEditField.Value = 600;
               h = 600;
               fE = (1/pi) * acos(sqrt(h^2 + 2 * app.Re * h) / ((app.Re + h) * cos(beta)));
            end
        end
        
        function T_ecl = eclipse_orbit_T(app)
            fE = eclipse_fraction(app);
            
            % Eclipse period.
            T_ecl =  fE * orbit_T(app);
        end
        
        function T_sun_ill = sun_illuminated_orbit_T(app)
            fE = eclipse_fraction(app);
            
            % Sun-illuminatd period.
            T_sun_ill = (1 - fE) * orbit_T(app);
        end
        
        function args = set_simiulation_args(app)
            % Orbit beta anble.
            app.orbit_beta = set_orbit_beta_angle(app);
 
            % Satellite's orbit characteristics.
            args.orbit.num = str2double(app.NumberofOrbitsDropDown.Value);
            args.orbit.T_ecl = eclipse_orbit_T(app);
            args.orbit.T_sun_ill = sun_illuminated_orbit_T(app);
            
            % Display orbit period data.
            app.PeriodminEditField.Value = (args.orbit.T_ecl + args.orbit.T_sun_ill) / 60;
            app.EclipsePeriodminEditField.Value = args.orbit.T_ecl / 60;
            app.SunIlluminatedPeriodminEditField.Value = args.orbit.T_sun_ill / 60;
            
            % Satellite coating emissivity and absorbivity properties.
            cargs = set_coating_args(app);
            args.satprop.coating.alpha = cargs.coating.alpha;
            args.satprop.coating.epsilon = cargs.coating.epsilon;
            
            % Satellite radius (it's a spherical satellite).
            args.satprop.radius = app.RadiusmEditField.Value;
            
            % Satellite mass.
            args.satprop.mass = app.MasskgEditField.Value;
            
            % Satellite thermal capacity.
            args.satprop.thermal_capacity = app.ThermalCapacityJKgKEditField.Value;
            
            % Satellite's initial temperature.
            args.satprop.T0_K = app.InitialTemperatureCEditField.Value + 273;
            
            % An amplifier inside the spacecraft radiates heat.
            args.satprop.radiating_heat = app.RadiatingHeatWEditField.Value;
            
            % Satellite thermal absorbing area.
            args.satprop.Aa = pi * args.satprop.radius^2;
            
            % Satellite thermal emitting area.
            args.satprop.Ae = 4 * pi * args.satprop.radius^2;
            
            append_index = length(app.plot_legend) + 1;
            app.plot_legend(1, append_index) = {set_legend(app, args)};
            
        end
        
        function legend_str = set_legend(app, args)
            legend_str = '';
            
            if app.borbitbetaangleCheckBox.Value == 1
                legend_str = strcat(legend_str, ', b=', num2str(app.orbit_beta));
            end
            
            if app.haltitudeCheckBox.Value == 1
                legend_str = strcat(legend_str, ', h=', num2str(app.AltitudekmEditField.Value));
            end
            
            if app.aecoatingCheckBox.Value == 1
                legend_str = strcat(legend_str,...
                    ', a=', num2str(args.satprop.coating.alpha),...
                    ', e=', num2str(args.satprop.coating.epsilon));
            end
            
            if app.rradiusCheckBox.Value == 1
                legend_str = strcat(legend_str, ', r=', num2str(args.satprop.radius));
            end
            
            if app.mmassCheckBox.Value == 1
                legend_str = strcat(legend_str, ', m=', num2str(args.satprop.mass));
            end
            
            if app.cthermalcapacityCheckBox.Value == 1
                legend_str = strcat(legend_str, ', c=', num2str(args.satprop.thermal_capacity));
            end
            
            if app.t0initialtemperatureCheckBox.Value == 1
                legend_str = strcat(legend_str, ', t0=', num2str(args.satprop.T0_K));
            end
            
            if app.qsradiatingheatCheckBox.Value == 1
                legend_str = strcat(legend_str, ', qs=', num2str(args.satprop.radiating_heat));
            end
            
            % Remove first comma.
            legend_str = extractAfter(legend_str, 2);
        end
        
        function beta = set_orbit_beta_angle(app)
            % There are two special type of Sun synchronous orbit Dawn-dusk orbit and Noon-midnight orbit.
            %   1. Dawn-dusk orbit.
            %       - The sun beta angle is 90 deg.
            %       - The orbit plane normal points the sun always.
            %       - This orbit doesn't undergo eclipses.
            %       - A spacecraft placed in this orbit passes through the terminator
            %         line of the earth i.e. day-night separator.
            %
            %   2. Noon-midnight orbit:
            %       - The sun beta angle is 0 deg.
            %       - The sun is always in the orbital plane.
            %
            %   Source:
            %   "What is the beta angle with respect to sun-synchronous satellites?"
            %   Rajesh Kannan, Scientist, Flight Dynamics Section, ISRO, MCF, Hassan, INDIA
            %   Quora.com, May 21, 2017.
            %   https://www.quora.com/What-is-the-beta-angle-with-respect-to-sun-synchronous-satellites

            if strcmp(app.ButtonGroup.SelectedObject.Text, 'Dawn-dusk') == 1
                beta = 90;
                
            elseif strcmp(app.ButtonGroup.SelectedObject.Text, 'Noon-midnight') == 1
                beta = 0;
            else
                error_msg = 'The selected orbit type was not recognized. This is an error in the code. Defaulting to orbit type noon-midnight.';
                uialert(app.UIFigure, error_msg, 'Error'); 
                beta = 0;
            end
        end
        
        function cargs = set_coating_args(app)
            coating = app.CoatingDropDown.Value;
            
            if strcmp(coating, 'White paint') == 1
                cargs.coating.alpha = 0.2;
                cargs.coating.epsilon = 0.9;
                
            elseif strcmp(coating, 'Black paint') == 1
                cargs.coating.alpha = 0.95;
                cargs.coating.epsilon = 0.9;
            
            elseif strcmp(coating, 'Aluminium (unpolished)') == 1
                cargs.coating.alpha = 0.25;
                cargs.coating.epsilon = 0.25;
                
            elseif strcmp(coating, 'Aluminium (polished)') == 1
                cargs.coating.alpha = 0.2;
                cargs.coating.epsilon = 0.05;
                
            elseif strcmp(coating, 'Gold') == 1
                cargs.coating.alpha = 0.25;
                cargs.coating.epsilon = 0.05;
                
            elseif strcmp(coating, 'Graphite epoxy') == 1
                cargs.coating.epsilon = 0.95;
                cargs.coating.alpha = 0.75;
                
            elseif strcmp(coating, 'Glass fibre') == 1
                cargs.coating.alpha = 0.9;
                cargs.coating.epsilon = 0.9;
                
            elseif strcmp(coating, 'Aluminized kapton') == 1
                cargs.coating.alpha = 0.5;
                cargs.coating.epsilon = 0.6;
                
            elseif strcmp(coating, 'Optical solar reflector (OSR)') == 1
                cargs.coating.alpha = 0.08;
                cargs.coating.epsilon = 0.8;
                
            elseif strcmp(coating, 'Second surface mirror (SSM)') == 1
                cargs.coating.alpha = 0.15;
                cargs.coating.epsilon = 0.8;
                
            elseif strcmp(coating, 'Solar cells, Si, filtered') == 1
                cargs.coating.alpha = 0.8;
                cargs.coating.epsilon = 0.9;
                
            else
                error_msg = 'The selected coating was not recognized. This is an error in the code. Defaulting to Aluminium (unpolished).';
                uialert(app.UIFigure, error_msg, 'Error'); 
                cargs.coating.alpha = 0.25;
                cargs.coating.epsilon = 0.25;
            end
        end
        
        function simplot = simulate_and_plot(app)
            args = set_simiulation_args(app);
            [ts, tc] = simulate(args.orbit, args.satprop);
                   
            append_index = length(app.time_span_list) + 1;
            app.time_span_list{1, append_index} = ts;
            app.T_C_list{1, append_index} = tc;
          
            plot(app.UIAxes, cell2mat(app.time_span_list(1)), cell2mat(app.T_C_list(1)), 'Linewidth', 1);
            hold(app.UIAxes, 'on');
            legend(app.UIAxes, app.plot_legend(1));

            for i = (2: 1: length(app.time_span_list))
                plot(app.UIAxes, cell2mat(app.time_span_list(i)), cell2mat(app.T_C_list(i)), 'Linewidth', 1); 
            end
            legend(app.UIAxes, app.plot_legend);
            hold(app.UIAxes, 'off');
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            simulate_and_plot(app);
        end

        % Value changed function: AltitudekmEditField
        function AltitudekmEditFieldValueChanged(app, event)
            %simulate_and_plot(app); 
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            
            %simulate_and_plot(app);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes);
            
            % Clear data to plot.
            app.time_span_list = {};
            app.T_C_list = {};
            app.plot_legend = {};
        end

        % Value changed function: CoatingDropDown
        function CoatingDropDownValueChanged(app, event)
            %simulate_and_plot(app);
        end

        % Value changed function: InitialTemperatureCEditField
        function InitialTemperatureCEditFieldValueChanged(app, event)
            %simulate_and_plot(app);
        end

        % Value changed function: MasskgEditField
        function MasskgEditFieldValueChanged(app, event)
            %simulate_and_plot(app); 
        end

        % Value changed function: NumberofOrbitsDropDown
        function NumberofOrbitsDropDownValueChanged(app, event)
            %simulate_and_plot(app);
        end

        % Value changed function: RadiatingHeatWEditField
        function RadiatingHeatWEditFieldValueChanged(app, event)
            %simulate_and_plot(app);
        end

        % Value changed function: RadiusmEditField
        function RadiusmEditFieldValueChanged(app, event)
            %simulate_and_plot(app);
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            simulate_and_plot(app);
        end

        % Value changed function: ThermalCapacityJKgKEditField
        function ThermalCapacityJKgKEditFieldValueChanged(app, event)
            %simulate_and_plot(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Thermal Transient Analysis of a Spherical Satellite in a Sun-Synchronous Orbit';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Satellite Thermal Transience')
            xlabel(app.UIAxes, 'Time, minutes')
            ylabel(app.UIAxes, 'Temperature, celsius')
            app.SunSynchronousOrbitPanel = uipanel(app.UIFigure);
            app.SunSynchronousOrbitPanel.Title = 'Sun-Synchronous Orbit';
            app.AltitudekmEditFieldLabel = uilabel(app.SunSynchronousOrbitPanel);
            app.AltitudekmEditFieldLabel.Text = 'Altitude (km)';
            app.AltitudekmEditField = uieditfield(app.SunSynchronousOrbitPanel, 'numeric');
            app.AltitudekmEditField.ValueChangedFcn = createCallbackFcn(app, @AltitudekmEditFieldValueChanged, true);
            app.AltitudekmEditField.Limits = [100 50000];
            app.AltitudekmEditField.Value = 600;
            app.PeriodminEditFieldLabel = uilabel(app.SunSynchronousOrbitPanel);
            app.PeriodminEditFieldLabel.Text = 'Period (min)';
            app.PeriodminEditField = uieditfield(app.SunSynchronousOrbitPanel, 'numeric');
            app.PeriodminEditField.Editable = 'off';
            app.EclipsePeriodminEditFieldLabel = uilabel(app.SunSynchronousOrbitPanel);
            app.EclipsePeriodminEditFieldLabel.Text = 'Eclipse Period (min)';
            app.EclipsePeriodminEditField = uieditfield(app.SunSynchronousOrbitPanel, 'numeric');
            app.EclipsePeriodminEditField.Editable = 'off';
            app.SunIlluminatedPeriodminEditFieldLabel = uilabel(app.SunSynchronousOrbitPanel);
            app.SunIlluminatedPeriodminEditFieldLabel.Text = 'Sun Illuminated Period (min)';
            app.SunIlluminatedPeriodminEditField = uieditfield(app.SunSynchronousOrbitPanel, 'numeric');
            app.SunIlluminatedPeriodminEditField.Editable = 'off';
            app.ButtonGroup = uibuttongroup(app.SunSynchronousOrbitPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.DawnduskButton = uiradiobutton(app.ButtonGroup);
            app.DawnduskButton.Text = 'Dawn-dusk';
            app.NoonmidnightButton = uiradiobutton(app.ButtonGroup);
            app.NoonmidnightButton.Text = 'Noon-midnight';
            app.NoonmidnightButton.Value = true;
            app.TypeLabel = uilabel(app.SunSynchronousOrbitPanel);
            app.TypeLabel.Text = 'Type';
            app.NumberofOrbitsDropDownLabel = uilabel(app.SunSynchronousOrbitPanel);
            app.NumberofOrbitsDropDownLabel.Text = 'Number of Orbits';
            app.NumberofOrbitsDropDown = uidropdown(app.SunSynchronousOrbitPanel);
            app.NumberofOrbitsDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.NumberofOrbitsDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofOrbitsDropDownValueChanged, true);
            app.NumberofOrbitsDropDown.Value = '5';
            app.SpacecraftPropertiesPanel = uipanel(app.UIFigure);
            app.SpacecraftPropertiesPanel.Title = 'Spacecraft Properties';
            app.InitialTemperatureCEditFieldLabel = uilabel(app.SpacecraftPropertiesPanel);
            app.InitialTemperatureCEditFieldLabel.Text = 'Initial Temperature (°C)';
            app.InitialTemperatureCEditField = uieditfield(app.SpacecraftPropertiesPanel, 'numeric');
            app.InitialTemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @InitialTemperatureCEditFieldValueChanged, true);
            app.InitialTemperatureCEditField.Limits = [-300 300];
            app.InitialTemperatureCEditField.Value = 10;
            app.ShapeDropDownLabel = uilabel(app.SpacecraftPropertiesPanel);
            app.ShapeDropDownLabel.Enable = 'off';
            app.ShapeDropDownLabel.Text = 'Shape';
            app.ShapeDropDown = uidropdown(app.SpacecraftPropertiesPanel);
            app.ShapeDropDown.Items = {'Sphere'};
            app.ShapeDropDown.Enable = 'off';
            app.ShapeDropDown.Value = 'Sphere';
            app.RadiusmEditFieldLabel = uilabel(app.SpacecraftPropertiesPanel);
            app.RadiusmEditFieldLabel.Text = 'Radius (m)';
            app.RadiusmEditField = uieditfield(app.SpacecraftPropertiesPanel, 'numeric');
            app.RadiusmEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusmEditFieldValueChanged, true);
            app.RadiusmEditField.Limits = [0.01 100];
            app.RadiusmEditField.Value = 1;
            app.MasskgEditFieldLabel = uilabel(app.SpacecraftPropertiesPanel);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.MasskgEditField = uieditfield(app.SpacecraftPropertiesPanel, 'numeric');
            app.MasskgEditField.ValueChangedFcn = createCallbackFcn(app, @MasskgEditFieldValueChanged, true);
            app.MasskgEditField.Limits = [1 50000];
            app.MasskgEditField.Value = 100;
            app.CoatingDropDownLabel = uilabel(app.SpacecraftPropertiesPanel);
            app.CoatingDropDownLabel.Text = 'Coating';
            app.CoatingDropDown = uidropdown(app.SpacecraftPropertiesPanel);
            app.CoatingDropDown.Items = {'White paint', 'Black paint', 'Aluminium (unpolished)', 'Aluminium (polished)', 'Gold', 'Graphite epoxy', 'Glass fibre', 'Aluminized kapton', 'Optical solar reflector (OSR)', 'Second surface mirror (SSM)', 'Solar cells, Si, filtered'};
            app.CoatingDropDown.ValueChangedFcn = createCallbackFcn(app, @CoatingDropDownValueChanged, true);
            app.CoatingDropDown.Value = 'Aluminium (unpolished)';
            app.ThermalCapacityJKgKEditFieldLabel = uilabel(app.SpacecraftPropertiesPanel);
            app.ThermalCapacityJKgKEditFieldLabel.Text = 'Thermal Capacity (J/Kg/K)';
            app.ThermalCapacityJKgKEditField = uieditfield(app.SpacecraftPropertiesPanel, 'numeric');
            app.ThermalCapacityJKgKEditField.ValueChangedFcn = createCallbackFcn(app, @ThermalCapacityJKgKEditFieldValueChanged, true);
            app.ThermalCapacityJKgKEditField.Limits = [0 10000];
            app.ThermalCapacityJKgKEditField.Value = 900;
            app.RadiatingHeatWEditFieldLabel = uilabel(app.SpacecraftPropertiesPanel);
            app.RadiatingHeatWEditFieldLabel.Text = 'Radiating Heat (W)';
            app.RadiatingHeatWEditField = uieditfield(app.SpacecraftPropertiesPanel, 'numeric');
            app.RadiatingHeatWEditField.ValueChangedFcn = createCallbackFcn(app, @RadiatingHeatWEditFieldValueChanged, true);
            app.RadiatingHeatWEditField.Limits = [0 10000];
            app.RadiatingHeatWEditField.Value = 200;
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.LegendPanel = uipanel(app.UIFigure);
            app.LegendPanel.Title = 'Legend';
            app.borbitbetaangleCheckBox = uicheckbox(app.LegendPanel);
            app.borbitbetaangleCheckBox.Text = 'b - orbit beta angle';
            app.haltitudeCheckBox = uicheckbox(app.LegendPanel);
            app.haltitudeCheckBox.Text = 'h - altitude';
            app.aecoatingCheckBox = uicheckbox(app.LegendPanel);
            app.aecoatingCheckBox.Text = 'a, e - coating';
            app.aecoatingCheckBox.Value = true;
            app.rradiusCheckBox = uicheckbox(app.LegendPanel);
            app.rradiusCheckBox.Text = 'r - radius';
            app.mmassCheckBox = uicheckbox(app.LegendPanel);
            app.mmassCheckBox.Text = 'm - mass';
            app.cthermalcapacityCheckBox = uicheckbox(app.LegendPanel);
            app.cthermalcapacityCheckBox.Text = 'c - thermal capacity';
            app.t0initialtemperatureCheckBox = uicheckbox(app.LegendPanel);
            app.t0initialtemperatureCheckBox.Text = 't0 - initial temperature';
            app.qsradiatingheatCheckBox = uicheckbox(app.LegendPanel);
            app.qsradiatingheatCheckBox.Text = 'qs - radiating heat';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end